classdef ocr < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        InputPanel         matlab.ui.container.Panel
        InputGrid          matlab.ui.container.GridLayout
        InputImage         matlab.ui.control.Image
        SettingsLabel      matlab.ui.control.Label
        SettingsPanel      matlab.ui.container.Panel
        SetttingsGrid      matlab.ui.container.GridLayout
        LayersPanel        matlab.ui.container.Panel
        LayersSelectGrid   matlab.ui.container.GridLayout
        RCheckBox          matlab.ui.control.CheckBox
        GCheckBox          matlab.ui.control.CheckBox
        BCheckBox          matlab.ui.control.CheckBox
        ThresholdPanel     matlab.ui.container.Panel
        SliderLabel        matlab.ui.control.Label
        ThresholdSlider    matlab.ui.control.Slider
        ButtonsPanel       matlab.ui.container.Panel
        ButtonsGrid        matlab.ui.container.GridLayout
        SelectImageButton  matlab.ui.control.Button
        RunOCRButton       matlab.ui.control.Button
        FontSelector       matlab.ui.control.DropDown
        OutputPanel        matlab.ui.container.Panel
        Label_2            matlab.ui.control.Label
        OCRResultTextArea  matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        RGBLayers = ActiveLayers;
        thresholdValue = 0.0;
        recognizer = Recognizer();
        segmentizer = Segmentizer();
        
        
        inputImage;
        output;
    end
    
    methods (Access = private)
        function writeParams(app)
            text = "Selected settings" + newline + "(binarized image will go here in finished app):" + newline;
            text = text + "Threshold:" + sprintf("%.2f", app.thresholdValue) + newline;
            text = text + "Layers:" + app.RGBLayers.getActiveLayers() + newline;
            
            app.SettingsLabel.Text = text;
        end
        
        function displayInputImage(app)
            app.InputImage.ImageSource = app.inputImage;
        end
        
        function writeOutput(app)
            app.OCRResultTextArea.Value = [app.output];
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.thresholdValue = app.ThresholdSlider.Value;
            app.RGBLayers.R = app.RCheckBox.Value;
            app.RGBLayers.G = app.GCheckBox.Value;
            app.RGBLayers.B = app.BCheckBox.Value;
               
            font = app.FontSelector.Value;
            
            app.recognizer = app.recognizer.createDataSet(font);
            writeParams(app);
        end

        % Value changed function: RCheckBox
        function RCheckBoxValueChanged(app, event)
            value = app.RCheckBox.Value;
            app.RGBLayers.R = value;
            writeParams(app);
        end

        % Value changed function: GCheckBox
        function GCheckBoxValueChanged(app, event)
            value = app.GCheckBox.Value;
            app.RGBLayers.G = value;
            writeParams(app);
        end

        % Value changed function: BCheckBox
        function BCheckBoxValueChanged(app, event)
            value = app.BCheckBox.Value;
            app.RGBLayers.B = value;
            writeParams(app);
        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)
            value = app.ThresholdSlider.Value;
            app.thresholdValue = value;
            writeParams(app);
        end

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
            [file,path] = uigetfile('*.JPG;*.PNG');
            
            if ~isequal(file, 0)
                app.inputImage = double(imread(fullfile(path, file)))/255;

            end
            displayInputImage(app);
        end

        % Value changed function: FontSelector
        function changeFontSet(app, event)
            value = app.FontSelector.Value;
            app.recognizer = app.recognizer.createDataSet(value);
        end

        % Button pushed function: RunOCRButton
        function RunOCR(app, event)
            out = "";
            letters = app.segmentizer.segmentize(app.inputImage)';
            for i = 1:size(letters)
                if isa(letters{i}, 'char')
                    out = out + letters(i);
                else
                    box = letters(i);
                    image = box{1, 1};
                    out = out + app.recognizer.recognizeCharacter(image);
                end
                app.output = out;
                writeOutput(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.InputPanel = uipanel(app.GridLayout);
            app.InputPanel.Title = 'Input';
            app.InputGrid = uigridlayout(app.InputPanel);
            app.InputImage = uiimage(app.InputGrid);
            app.SettingsLabel = uilabel(app.InputGrid);
            app.SettingsPanel = uipanel(app.GridLayout);
            app.SettingsPanel.Title = 'Settings';
            app.SetttingsGrid = uigridlayout(app.SettingsPanel);
            app.LayersPanel = uipanel(app.SetttingsGrid);
            app.LayersPanel.Title = 'Layers';
            app.LayersSelectGrid = uigridlayout(app.LayersPanel);
            app.RCheckBox = uicheckbox(app.LayersSelectGrid);
            app.RCheckBox.ValueChangedFcn = createCallbackFcn(app, @RCheckBoxValueChanged, true);
            app.RCheckBox.Text = 'R';
            app.GCheckBox = uicheckbox(app.LayersSelectGrid);
            app.GCheckBox.ValueChangedFcn = createCallbackFcn(app, @GCheckBoxValueChanged, true);
            app.GCheckBox.Text = 'G';
            app.BCheckBox = uicheckbox(app.LayersSelectGrid);
            app.BCheckBox.ValueChangedFcn = createCallbackFcn(app, @BCheckBoxValueChanged, true);
            app.BCheckBox.Text = 'B';
            app.ThresholdPanel = uipanel(app.SetttingsGrid);
            app.ThresholdPanel.Title = 'Threshold';
            app.SliderLabel = uilabel(app.ThresholdPanel);
            app.SliderLabel.Text = '';
            app.ThresholdSlider = uislider(app.ThresholdPanel);
            app.ThresholdSlider.Limits = [0 1];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.ButtonsPanel = uipanel(app.SetttingsGrid);
            app.ButtonsGrid = uigridlayout(app.ButtonsPanel);
            app.SelectImageButton = uibutton(app.ButtonsGrid, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            app.RunOCRButton = uibutton(app.ButtonsGrid, 'push');
            app.RunOCRButton.ButtonPushedFcn = createCallbackFcn(app, @RunOCR, true);
            app.RunOCRButton.Text = 'Run OCR';
            app.FontSelector = uidropdown(app.ButtonsGrid);
            app.FontSelector.Items = {'Arial', 'Helvetica', 'TimesNewRoman'};
            app.FontSelector.ValueChangedFcn = createCallbackFcn(app, @changeFontSet, true);
            app.FontSelector.Value = 'Arial';
            app.OutputPanel = uipanel(app.GridLayout);
            app.OutputPanel.Title = 'Output';
            app.Label_2 = uilabel(app.OutputPanel);
            app.Label_2.Text = '';
            app.OCRResultTextArea = uitextarea(app.OutputPanel);
            app.OCRResultTextArea.Value = {'OCR Results go here.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ocr

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end